classdef UI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FFT                             matlab.ui.control.UIAxes
        signal_generate                 matlab.ui.control.Button
        AntennaconfigurationPanel       matlab.ui.container.Panel
        samplefrequencyLabel            matlab.ui.control.Label
        sample_frequence                matlab.ui.control.NumericEditField
        numberofchirpsEditFieldLabel    matlab.ui.control.Label
        number_of_chirps                matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel         matlab.ui.control.Label
        band_width                      matlab.ui.control.NumericEditField
        numberofsamplesEditFieldLabel   matlab.ui.control.Label
        number_of_samples               matlab.ui.control.NumericEditField
        TxpositionsLabel                matlab.ui.control.Label
        Tx_positions                    matlab.ui.control.TextArea
        RxpositionsLabel                matlab.ui.control.Label
        Rx_positionns                   matlab.ui.control.TextArea
        GHzLabel                        matlab.ui.control.Label
        wavelengthLabel                 matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        carrierfrequencyEditFieldLabel  matlab.ui.control.Label
        carrier_frequency               matlab.ui.control.NumericEditField
        GHzLabel_2                      matlab.ui.control.Label
        ux_range                        matlab.ui.control.UIAxes
        uy_range                        matlab.ui.control.UIAxes
        MHzLabel                        matlab.ui.control.Label
        ObjectconfigrationPanel         matlab.ui.container.Panel
        SNRLabel                        matlab.ui.control.Label
        variance                        matlab.ui.control.NumericEditField
        rangesEditFieldLabel            matlab.ui.control.Label
        ranges                          matlab.ui.control.EditField
        speedsEditFieldLabel            matlab.ui.control.Label
        speeds                          matlab.ui.control.EditField
        DOATextAreaLabel                matlab.ui.control.Label
        DOA                             matlab.ui.control.TextArea
        mLabel                          matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        amplitudesLabel                 matlab.ui.control.Label
        amplitudes                      matlab.ui.control.EditField
        TagetsListTextAreaLabel         matlab.ui.control.Label
        TargetListsLabel                matlab.ui.control.Label
        TagetsListTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        radarParameter;
        objectParameter;
        generated_signal;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: signal_generate
        function signal_generateButtonPushed(app, event)
            % set radar parameters
            f0 = app.carrier_frequency.Value * 1e9;
            B = app.band_width.Value * 1e9;
            fs = app.sample_frequence.Value * 1e6;
            N_chirp = app.number_of_chirps.Value;
            N_sample = app.number_of_samples.Value;
            Tx_P = str2num(app.Tx_positions.Value{1});
            Rx_P = str2num(app.Rx_positionns.Value{1});
            N_antenna = length(Tx_P) * length(Rx_P);
            app.radarParameter = defineRadar(f0, B, fs, N_chirp, N_sample, Tx_P, Rx_P);
            
            % set object parameters
            r0 = str2num(app.ranges.Value);
            vr = str2num(app.speeds.Value);
            u = str2num(app.DOA.Value{1});
            A = str2num(app.amplitudes.Value);
            SNR = app.variance.Value;
            num_Object = length(r0);
            % generate signal of first object
            app.objectParameter = defineObject(r0(1), vr(1), u(1,:), A(1), SNR);
            
            app.generated_signal = signalGenerator_SO(app.radarParameter, app.objectParameter);
            
            % generate signal of all the objects if there are
            if num_Object >= 2
                for i = 2: num_Object
                    app.objectParameter = defineObject(r0(i), vr(i), u(i,:), A(i), SNR);
                    app.generated_signal = app.generated_signal + signalGenerator_SO(app.radarParameter, app.objectParameter);
                end
            end
            %plot
            [targetList, range_doppler_fig, range_ux_fig, range_uy_fig] = new_signalProcessing(app.generated_signal, app.radarParameter);
            cellArrayText = {};
            for i = 1 : size(targetList, 1)
                cellArrayText{i} = sprintf('%0.2f, %0.2f, %0.2f, %0.2f\n', targetList(i, :));
            end
            app.TagetsListTextArea.Value = cellArrayText;
            range_ux_fig = range_ux_fig/max(range_ux_fig(:));
            range_ux_fig = flip(range_ux_fig, 1);
            
            range_uy_fig = range_uy_fig/max(range_uy_fig(:));
            range_uy_fig = flip(range_uy_fig, 1);
            
            range_doppler_fig = range_doppler_fig/max(range_doppler_fig(:));
            range_doppler_fig = flip(range_doppler_fig, 1);
            range_doppler_fig = flip(range_doppler_fig, 2);
            
            plot_range_doppler(range_doppler_fig, app.FFT);
            plot_range_DOA(range_ux_fig, "ux", app.ux_range);
            plot_range_DOA(range_uy_fig, "uy", app.uy_range);
              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FFT = uiaxes(app.UIFigure);
            title(app.FFT, 'range-Doppler FFT')
            xlabel(app.FFT, '')
            ylabel(app.FFT, '')
            app.FFT.XTick = [];
            app.FFT.XTickLabel = '';
            app.FFT.YTick = [];
            app.FFT.YTickLabel = '';
            app.signal_generate = uibutton(app.UIFigure, 'push');
            app.signal_generate.ButtonPushedFcn = createCallbackFcn(app, @signal_generateButtonPushed, true);
            app.signal_generate.Text = {'genrate'; ''};
            app.AntennaconfigurationPanel = uipanel(app.UIFigure);
            app.AntennaconfigurationPanel.Title = 'Antenna configuration';
            app.samplefrequencyLabel = uilabel(app.AntennaconfigurationPanel);
            app.samplefrequencyLabel.Text = 'sample frequency';
            app.sample_frequence = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.sample_frequence.Value = 10;
            app.numberofchirpsEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofchirpsEditFieldLabel.Text = 'number of chirps';
            app.number_of_chirps = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_chirps.ValueDisplayFormat = '%.0f';
            app.number_of_chirps.Value = 160;
            app.bandwidthEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.band_width = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.band_width.Value = 3;
            app.numberofsamplesEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofsamplesEditFieldLabel.Text = 'number of samples';
            app.number_of_samples = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_samples.ValueDisplayFormat = '%.0f';
            app.number_of_samples.Value = 1000;
            app.TxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.TxpositionsLabel.Text = 'Tx positions';
            app.Tx_positions = uitextarea(app.AntennaconfigurationPanel);
            app.Tx_positions.Value = {'[0, 0]'};
            app.RxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.RxpositionsLabel.Text = 'Rx positions';
            app.Rx_positionns = uitextarea(app.AntennaconfigurationPanel);
            app.Rx_positionns.Value = {'[0, 0; 1,0; 0,1; 1,1]'};
            app.GHzLabel = uilabel(app.AntennaconfigurationPanel);
            app.GHzLabel.Text = 'GHz';
            app.GHzLabel_2 = uilabel(app.AntennaconfigurationPanel);
            app.GHzLabel_2.Text = 'GHz';
            app.MHzLabel = uilabel(app.AntennaconfigurationPanel);
            app.MHzLabel.Text = 'MHz';
            app.wavelengthLabel = uilabel(app.AntennaconfigurationPanel);
            app.wavelengthLabel.Text = '0.5wavelength';
            app.mLabel_3 = uilabel(app.AntennaconfigurationPanel);
            app.mLabel_3.Text = 'm';
            app.carrierfrequencyEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.carrierfrequencyEditFieldLabel.Text = 'carrier frequency';
            app.carrier_frequency = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.carrier_frequency.Value = 77;
            app.ObjectconfigrationPanel = uipanel(app.UIFigure);
            app.ObjectconfigrationPanel.Title = 'Object configration';
            app.SNRLabel = uilabel(app.ObjectconfigrationPanel);
            app.SNRLabel.Text = 'SNR';
            app.variance = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.variance.Value = 8;
            app.rangesEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.rangesEditFieldLabel.Text = 'ranges';
            app.ranges = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.ranges.Value = '[30, 70, 120]';
            app.speedsEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.speedsEditFieldLabel.Text = 'speeds';
            app.speeds = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.speeds.Value = '[-10, -5, 10]';
            app.DOATextAreaLabel = uilabel(app.ObjectconfigrationPanel);
            app.DOATextAreaLabel.Text = 'DOA';
            app.DOA = uitextarea(app.ObjectconfigrationPanel);
            app.DOA.Value = {'[0.4, 0.4; 0.3, 0.25; -0.3, 0.3]'};
            app.mLabel = uilabel(app.ObjectconfigrationPanel);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.ObjectconfigrationPanel);
            app.msLabel.Text = 'm/s';
            app.amplitudesLabel = uilabel(app.ObjectconfigrationPanel);
            app.amplitudesLabel.Text = 'amplitudes';
            app.amplitudes = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.amplitudes.Value = '[1,1,1]';
            app.dBLabel = uilabel(app.ObjectconfigrationPanel);
            app.dBLabel.Text = 'dB';
            app.TagetsListTextAreaLabel = uilabel(app.UIFigure);
            app.TagetsListTextAreaLabel.Text = 'Tagets List';
            app.TagetsListTextArea = uitextarea(app.UIFigure);
            app.ux_range = uiaxes(app.UIFigure);
            title(app.ux_range, 'ux-range')
            xlabel(app.ux_range, '')
            ylabel(app.ux_range, '')
            app.ux_range.XTick = [];
            app.ux_range.XTickLabel = '';
            app.ux_range.YTick = [];
            app.ux_range.YTickLabel = '';
            app.uy_range = uiaxes(app.UIFigure);
            title(app.uy_range, 'uy-range')
            xlabel(app.uy_range, '')
            ylabel(app.uy_range, '')
            app.uy_range.XTick = [];
            app.uy_range.XTickLabel = '';
            app.uy_range.YTick = [];
            app.uy_range.YTickLabel = '';
            app.TargetListsLabel = uilabel(app.UIFigure);
            app.TargetListsLabel.Text = 'Target Lists';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end