classdef UI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        radarsimulationsystemUIFigure   matlab.ui.Figure
        range_Dppler                    matlab.ui.control.UIAxes
        AntennaconfigurationPanel       matlab.ui.container.Panel
        samplefrequencyLabel            matlab.ui.control.Label
        sample_frequence                matlab.ui.control.NumericEditField
        numberofchirpsEditFieldLabel    matlab.ui.control.Label
        number_of_chirps                matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel         matlab.ui.control.Label
        band_width                      matlab.ui.control.NumericEditField
        numberofsamplesEditFieldLabel   matlab.ui.control.Label
        number_of_samples               matlab.ui.control.NumericEditField
        TxpositionsLabel                matlab.ui.control.Label
        Tx_positions                    matlab.ui.control.TextArea
        RxpositionsLabel                matlab.ui.control.Label
        Rx_positionns                   matlab.ui.control.TextArea
        GHzLabel                        matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        Notetheunitofpositionsis05wavelengthLabel  matlab.ui.control.Label
        carrierfrequencyEditFieldLabel  matlab.ui.control.Label
        carrier_frequency               matlab.ui.control.NumericEditField
        ObjectconfigrationPanel         matlab.ui.container.Panel
        SNRLabel                        matlab.ui.control.Label
        variance                        matlab.ui.control.NumericEditField
        rangesEditFieldLabel            matlab.ui.control.Label
        ranges                          matlab.ui.control.EditField
        speedsEditFieldLabel            matlab.ui.control.Label
        speeds                          matlab.ui.control.EditField
        DOATextAreaLabel                matlab.ui.control.Label
        DOA                             matlab.ui.control.TextArea
        mLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        amplitudesLabel                 matlab.ui.control.Label
        amplitudes                      matlab.ui.control.EditField
        dBLabel                         matlab.ui.control.Label
        TagetsListTextAreaLabel         matlab.ui.control.Label
        TagetsListTextArea              matlab.ui.control.TextArea
        TargetListsLabel                matlab.ui.control.Label
        estimation                      matlab.ui.control.Button
        range_uy                        matlab.ui.control.UIAxes
        range_ux                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        radarParameter;
        objectParameter;
        generated_signal;
        range_ux_fig_out;
        range_uy_fig_out;
        range_doppler_fig_out;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: estimation
        function estimationButtonPushed(app, event)
            % set radar parameters
            f0 = app.carrier_frequency.Value * 1e9;
            B = app.band_width.Value * 1e6;
            fs = app.sample_frequence.Value * 1e6;
            N_chirp = app.number_of_chirps.Value;
            N_sample = app.number_of_samples.Value;
            Tx_P = str2num(app.Tx_positions.Value{1});
            Rx_P = str2num(app.Rx_positionns.Value{1});
            app.radarParameter = defineRadar(f0, B, fs, N_chirp, N_sample, Tx_P, Rx_P);
            
            % set object parameters
            r0 = str2num(app.ranges.Value);
            vr = str2num(app.speeds.Value);
            u = str2num(app.DOA.Value{1});
            A = str2num(app.amplitudes.Value);
            SNR = app.variance.Value;
            num_Object = length(r0);
            % generate signal of first object
            app.objectParameter = defineObject(r0(1), vr(1), u(1,:), A(1), SNR);
            
            app.generated_signal = signalGenerator_SO(app.radarParameter, app.objectParameter);
            
            % generate signal of all the objects if there are
            if num_Object >= 2
                for i = 2: num_Object
                    app.objectParameter = defineObject(r0(i), vr(i), u(i,:), A(i), SNR);
                    app.generated_signal = app.generated_signal + signalGenerator_SO(app.radarParameter, app.objectParameter);
                end
            end
            
            %plot
            [targetList, range_doppler_fig, range_ux_fig, range_uy_fig] = new_signalProcessing(app.generated_signal, app.radarParameter);
            cellArrayText = {};
            cellArrayText{1} = sprintf('%-16s %-15s %-15s %-15s\n', 'r0', 'v', 'ux', 'uy');
            for i = 2 : size(targetList, 1) + 1
                cellArrayText{i} = sprintf('%-10.2f   %-10.2f   %-10.2f   %-10.2f\n', targetList(i-1, :));
            end
            
            app.TagetsListTextArea.Value = cellArrayText;
            range_ux_fig = range_ux_fig/max(range_ux_fig(:));
            app.range_ux_fig_out = flip(range_ux_fig, 1);
            
            range_uy_fig = range_uy_fig/max(range_uy_fig(:));
            app.range_uy_fig_out = flip(range_uy_fig, 1);
            
            range_doppler_fig = range_doppler_fig/max(range_doppler_fig(:));
            range_doppler_fig = flip(range_doppler_fig, 1);
            app.range_doppler_fig_out = flip(range_doppler_fig, 2);     
            
            plot_range_doppler(app.range_doppler_fig_out, app.range_Dppler);
            
            plot_range_DOA(app.range_ux_fig_out, "ux", app.range_ux);
            
            plot_range_DOA(app.range_uy_fig_out, "uy", app.range_uy);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.radarsimulationsystemUIFigure = uifigure('Visible', 'off');
            app.radarsimulationsystemUIFigure.Name = 'radar simulation system';
            app.range_Dppler = uiaxes(app.radarsimulationsystemUIFigure);
            title(app.range_Dppler, 'range-Doppler')
            xlabel(app.range_Dppler, '')
            ylabel(app.range_Dppler, '')
            app.AntennaconfigurationPanel = uipanel(app.radarsimulationsystemUIFigure);
            app.AntennaconfigurationPanel.Title = 'Antenna configuration';
            app.samplefrequencyLabel = uilabel(app.AntennaconfigurationPanel);
            app.samplefrequencyLabel.Text = 'sample frequency';
            app.sample_frequence = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.sample_frequence.Value = 20.36;
            app.numberofchirpsEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofchirpsEditFieldLabel.Text = 'number of chirps';
            app.number_of_chirps = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_chirps.ValueDisplayFormat = '%.0f';
            app.number_of_chirps.Value = 256;
            app.bandwidthEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.band_width = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.band_width.Value = 224;
            app.numberofsamplesEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.numberofsamplesEditFieldLabel.Text = 'number of samples';
            app.number_of_samples = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.number_of_samples.ValueDisplayFormat = '%.0f';
            app.number_of_samples.Value = 238;
            app.TxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.TxpositionsLabel.Text = 'Tx positions';
            app.Tx_positions = uitextarea(app.AntennaconfigurationPanel);
            app.Tx_positions.Value = {'[0,0;3.4761,6.1928;7.6331,4.2305;9.5168,8.8576]'};
            app.RxpositionsLabel = uilabel(app.AntennaconfigurationPanel);
            app.RxpositionsLabel.Text = 'Rx positions';
            app.Rx_positionns = uitextarea(app.AntennaconfigurationPanel);
            app.Rx_positionns.Value = {'[0.2227,1.0822; 9.8557,8.1197;1.6568,0.3997;10.0000,10.0000]'};
            app.GHzLabel = uilabel(app.AntennaconfigurationPanel);
            app.GHzLabel.Text = 'GHz';
            app.MHzLabel_2 = uilabel(app.AntennaconfigurationPanel);
            app.MHzLabel_2.Text = 'MHz';
            app.MHzLabel = uilabel(app.AntennaconfigurationPanel);
            app.MHzLabel.Text = 'MHz';
            app.carrierfrequencyEditFieldLabel = uilabel(app.AntennaconfigurationPanel);
            app.carrierfrequencyEditFieldLabel.Text = 'carrier frequency';
            app.carrier_frequency = uieditfield(app.AntennaconfigurationPanel, 'numeric');
            app.carrier_frequency.Value = 77;
            app.Notetheunitofpositionsis05wavelengthLabel = uilabel(app.AntennaconfigurationPanel);
            app.Notetheunitofpositionsis05wavelengthLabel.Text = 'Note: the unit of positions is 0.5 wavelength';
            app.ObjectconfigrationPanel = uipanel(app.radarsimulationsystemUIFigure);
            app.ObjectconfigrationPanel.Title = 'Object configration';
            app.SNRLabel = uilabel(app.ObjectconfigrationPanel);
            app.SNRLabel.Text = 'SNR';
            app.variance = uieditfield(app.ObjectconfigrationPanel, 'numeric');
            app.variance.Value = 8;
            app.rangesEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.rangesEditFieldLabel.Text = 'ranges';
            app.ranges = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.ranges.Value = '[30, 70, 120]';
            app.speedsEditFieldLabel = uilabel(app.ObjectconfigrationPanel);
            app.speedsEditFieldLabel.Text = 'speeds';
            app.speeds = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.speeds.Value = '[-10, -5, 10]';
            app.DOATextAreaLabel = uilabel(app.ObjectconfigrationPanel);
            app.DOATextAreaLabel.Text = 'DOA';
            app.DOA = uitextarea(app.ObjectconfigrationPanel);
            app.DOA.Value = {'[0.4, 0.4; 0.3, 0.25; -0.3, 0.3]'};
            app.mLabel = uilabel(app.ObjectconfigrationPanel);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.ObjectconfigrationPanel);
            app.msLabel.Text = 'm/s';
            app.amplitudesLabel = uilabel(app.ObjectconfigrationPanel);
            app.amplitudesLabel.Text = 'amplitudes';
            app.amplitudes = uieditfield(app.ObjectconfigrationPanel, 'text');
            app.amplitudes.Value = '[1,1,1]';
            app.dBLabel = uilabel(app.ObjectconfigrationPanel);
            app.dBLabel.Text = 'dB';
            app.TagetsListTextAreaLabel = uilabel(app.radarsimulationsystemUIFigure);
            app.TagetsListTextAreaLabel.Text = 'Tagets List';
            app.TagetsListTextArea = uitextarea(app.radarsimulationsystemUIFigure);
            app.TargetListsLabel = uilabel(app.radarsimulationsystemUIFigure);
            app.TargetListsLabel.Text = 'Target Lists';
            app.estimation = uibutton(app.radarsimulationsystemUIFigure, 'push');
            app.estimation.ButtonPushedFcn = createCallbackFcn(app, @estimationButtonPushed, true);
            app.estimation.Text = 'estimation';
            app.range_uy = uiaxes(app.radarsimulationsystemUIFigure);
            title(app.range_uy, 'range-uy')
            xlabel(app.range_uy, '')
            ylabel(app.range_uy, '')
            app.range_ux = uiaxes(app.radarsimulationsystemUIFigure);
            title(app.range_ux, 'range-ux')
            xlabel(app.range_ux, '')
            ylabel(app.range_ux, '')
            % Show the figure after all components are created
            app.radarsimulationsystemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.radarsimulationsystemUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.radarsimulationsystemUIFigure)
        end
    end
end